classdef headfix_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        HeadfixedbehaviorMenu  matlab.ui.container.Menu
        prefs                  matlab.ui.container.Menu
        CloseMenuItem          matlab.ui.container.Menu
        activityAxes           matlab.ui.control.UIAxes
        text24                 matlab.ui.control.Label
        rewards                matlab.ui.control.Label
        text98                 matlab.ui.control.Label
        text97                 matlab.ui.control.Label
        text96                 matlab.ui.control.Label
        text95                 matlab.ui.control.Label
        text87                 matlab.ui.control.Label
        availablePorts         matlab.ui.control.DropDown
        openButton             matlab.ui.control.Button
        closeButton            matlab.ui.control.Button
        refreshButton          matlab.ui.control.Button
        portLabel              matlab.ui.control.Label
        testcs1                matlab.ui.control.Button
        testcs2                matlab.ui.control.Button
        fileName               matlab.ui.control.EditField
        startButton            matlab.ui.control.Button
        labelPort              matlab.ui.control.Label
        stopButton             matlab.ui.control.Button
        port                   matlab.ui.control.EditField
        labelFile              matlab.ui.control.Label
        licks1Edit             matlab.ui.control.EditField
        cues1Edit              matlab.ui.control.EditField
        text11                 matlab.ui.control.Label
        text12                 matlab.ui.control.Label
        startText              matlab.ui.control.EditField
        text15                 matlab.ui.control.Label
        bgdsolenoidsEdit       matlab.ui.control.EditField
        fxdsolenoids1Edit      matlab.ui.control.EditField
        text27                 matlab.ui.control.Label
        text28                 matlab.ui.control.Label
        text29                 matlab.ui.control.Label
        text30                 matlab.ui.control.Label
        text31                 matlab.ui.control.Label
        text32                 matlab.ui.control.Label
        text33                 matlab.ui.control.Label
        cues3Edit              matlab.ui.control.EditField
        text34                 matlab.ui.control.Label
        sendButton             matlab.ui.control.Button
        uipanel4               matlab.ui.container.Panel
        text2                  matlab.ui.control.Label
        ITIlabel               matlab.ui.control.Label
        mindelaybgdtocue       matlab.ui.control.EditField
        text20                 matlab.ui.control.Label
        T_bgd                  matlab.ui.control.EditField
        r_bgd                  matlab.ui.control.EditField
        mindelayfxdtobgd       matlab.ui.control.EditField
        text25                 matlab.ui.control.Label
        text40                 matlab.ui.control.Label
        checkboxtrialbytrial   matlab.ui.control.CheckBox
        text58                 matlab.ui.control.Label
        totPoisssolenoid       matlab.ui.control.EditField
        text80                 matlab.ui.control.Label
        backgroundsolenoid     matlab.ui.control.EditField
        testlaser              matlab.ui.control.Button
        testcs3                matlab.ui.control.Button
        text81                 matlab.ui.control.Label
        text82                 matlab.ui.control.Label
        text83                 matlab.ui.control.Label
        text84                 matlab.ui.control.Label
        fxdsolenoids2Edit      matlab.ui.control.EditField
        cues2Edit              matlab.ui.control.EditField
        text85                 matlab.ui.control.Label
        text86                 matlab.ui.control.Label
        licks2Edit             matlab.ui.control.EditField
        text88                 matlab.ui.control.Label
        text89                 matlab.ui.control.Label
        text90                 matlab.ui.control.Label
        text93                 matlab.ui.control.Label
        csproperties           matlab.ui.control.Table
        text94                 matlab.ui.control.Label
        licks3Edit             matlab.ui.control.EditField
        text99                 matlab.ui.control.Label
        fxdsolenoids3Edit      matlab.ui.control.EditField
        text100                matlab.ui.control.Label
        text101                matlab.ui.control.Label
        fxdsolenoids4Edit      matlab.ui.control.EditField
        text102                matlab.ui.control.Label
        lickproperties         matlab.ui.control.Table
        uipanel16              matlab.ui.container.Panel
        experimentmode         matlab.ui.control.DropDown
        uploadButton           matlab.ui.control.Button
        uipanel11              matlab.ui.container.Panel
        solenoid2Button        matlab.ui.control.Button
        uipanel15              matlab.ui.container.Panel
        solenoid4Button        matlab.ui.control.Button
        uipanel14              matlab.ui.container.Panel
        solenoid3Button        matlab.ui.control.Button
        uipanel10              matlab.ui.container.Panel
        solenoid1Button        matlab.ui.control.Button
        uipanel12              matlab.ui.container.Panel
        vacuumButton           matlab.ui.control.Button
        uipanel3               matlab.ui.container.Panel
        text14                 matlab.ui.control.Label
        maxITI                 matlab.ui.control.EditField
        text17                 matlab.ui.control.Label
        minITI                 matlab.ui.control.EditField
        checkboxexpiti         matlab.ui.control.CheckBox
        text91                 matlab.ui.control.Label
        maxdelaycuetovacuum    matlab.ui.control.EditField
        text92                 matlab.ui.control.Label
        uipanel1               matlab.ui.container.Panel
        text66                 matlab.ui.control.Label
        laserlatency           matlab.ui.control.EditField
        text65                 matlab.ui.control.Label
        text59                 matlab.ui.control.Label
        lasertrialbytrial      matlab.ui.control.CheckBox
        laserduration          matlab.ui.control.EditField
        checkboxrandlaser      matlab.ui.control.CheckBox
        laserpulseoffperiod    matlab.ui.control.EditField
        text57                 matlab.ui.control.Label
        text56                 matlab.ui.control.Label
        laserpulseperiod       matlab.ui.control.EditField
        light1Edit             matlab.ui.control.EditField
        text106                matlab.ui.control.Label
        text107                matlab.ui.control.Label
        light2Edit             matlab.ui.control.EditField
        text108                matlab.ui.control.Label
        text109                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function headfix_GUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to headfix_GUI (see VARARGIN)
%             
%             % Choose default command line output for headfix_GUI
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % This sets up the initial plot - only do when we are invisible
%             % so window can get raised using headfix_GUI.
%             % if strcmp(get(hObject,'Visible'),'off')
%             %     plot(rand(5));
%             % end
%             
%             global actvAx saveDir
%             
%             mainPath = 'C:\Users\stuberadmin\Dropbox (Stuber Lab)\Vijay\Behavioral acquisition and analysis';
%             addpath(mainPath)
%             saveDir = [mainPath '\data\'];          % where to save data
             
%             actvAx  = app.activityAxes;         % handle for activity plot
%             
%             % Find available serial ports
%             serialInfo = instrhwinfo('serial');
%             port = serialInfo.AvailableSerialPorts;
%             if ~isempty(port)
%                 set(app.availablePorts,'String',port)
%             end
%             
%             % Change window title
%             set(gcf,'name','Head-fixed behavior')
            csData = {'Number of trials' 33 33 33;
                      'Frequency(kHz)' 12 3 7;
                      'Predicted solenoid' '1+3' '2+3' '1+3';
                      'Probability of solenoid' '100+100' '100+100' '0+100';
                      'Solenoid open time (ms)' '30+30' '30+30' '0+30';
                      'Cue duration (ms)' 1000 1000 1000;
                      'Delay to solenoid (ms)' '1500+3000' '1500+3000' '0+3000';
                      'Pulse tone (1) or not (0)' 0 0 1;
                      'Speaker number' 1 2 1;
                      'Go lick requirement' 1 1 0;
                      'Go lick tube (or solenoid)' 1 2 3;
                      'Sound(1), light(2) or both(3)' 1 1 1;
                };
            app.csproperties.Data = csData;
            app.csproperties.RowName = '';
            lickData = {'Number of licks required' 1 1;
                        'Predicted solenoid' 3 3;
                        'Probability of solenoid' 100 100;
                        'Solenoid open time (ms)' 30 30;
                        'Delay to solenoid (ms)' 0 0;
                        'Delay to next lick (ms)' 6000 6000;
                        'Min number of rewards' 20 20;
                        'Sound(1), light(2) or both(3)' 1 1;
                        'Pulse tone (1) or not (0)' 0 1;
                        'Sound Frequency (kHz)' 12 3;
                        'Sound Duration (ms)' 1000 1000;
                        'Speaker number' 1 2;
                        };
            app.lickproperties.Data = lickData;
        end

        % Menu selected function: CloseMenuItem
        function CloseMenuItem_Callback(app, event)
            % hObject    handle to CloseMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selection = questdlg(['Close ' get(app.figure1,'Name') '?'],...
                                 ['Close ' get(app.figure1,'Name') '...'],...
                                 'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            
            delete(app.figure1)
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            
            global s
            fclose(s)
            instrreset                                          % "closes serial"
            set(app.port,'String','port not selected')      % remove port from menu
            set(app.startButton,'Enable','off')             % turn off 'start' button
            set(app.closeButton,'Enable','off')             % turn off 'unlink' button (self)
            set(app.openButton,'Enable','on')               % turn on 'link' button
            set(app.refreshButton,'Enable','on')            % turn on 'refresh' button
        end

        % Value changed function: experimentmode
        function experimentmode_Callback(app, event)
            % hObject    handle to experimentmode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns experimentmode contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from experimentmode
            contents = cellstr(get(hObjects,'String'));
            selection = contents{get(hObjects,'Value')};
            
            if strcmp(selection, '1: Cues with or without lick req')
                set(hObjects, 'Value') = 1;
            elseif strcmp(selection, '2: Random rewards (lick training)')
                set(hObjects, 'Value') = 2;
            elseif strcmp(selection, '3: Lick for rewards')
                set(hObjects, 'Value') = 3;
            end
            
            set(app.uploadButton, 'Enable', 'on');
        end

        % Button pushed function: openButton
        function openButton_Callback(app, event)
            % opens serial port (identified by user) for communication with arduino
            
            global s
            
            portList = get(app.availablePorts,'String');    % get list from popup menu
            selected = get(app.availablePorts,'Value');     % find which is selected
            port     = portList{selected};                      % selected port
            
            s = serial(port,'BaudRate',9600,'Timeout',1);    % setup serial port with arduino, specify the terminator as a LF ('\n' in Arduino)
            fopen(s)                                            % open serial port with arduino
            % get(s)
            set(app.openButton,'String','Wait 5s');
            pause(5);
            set(app.openButton,'String','Link');
            
            set(app.port,'String',port)                     % write out port selected in menu
            set(app.sendButton,'Enable','on')               % enable 'send' button
            set(app.startButton,'Enable','off')             % disable 'send' button
            set(app.closeButton,'Enable','on')              % enable 'unlink' button
            set(app.openButton,'Enable','off')              % disable 'link' button
            set(app.refreshButton,'Enable','off')           % disable 'refresh' button
            set(app.experimentmode,'Enable','on')           % enable 'experimentmode' selection
        end

        % Button pushed function: refreshButton
        function refreshButton_Callback(app, event)
            % hObject    handle to refreshButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            serialInfo = instrhwinfo('serial');                             % get info on connected serial ports
            port = serialInfo.AvailableSerialPorts;                         % get names of ports
            if ~isempty(port)
                set(app.availablePorts,'String',port)                   % update list of ports available
            else
                set(app.availablePorts,'String', ...
                    'none found, please check connection and refresh')      % if none, indicate so
            end
        end

        % Button pushed function: sendButton
        function sendButton_Callback(app, event)
            % hObject    handle to sendButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global s;
            
            % Retrieve inputs
            
            % Experiment mode
            experimentmode = get(app.experimentmode,'Value');
            
            % Cues
            csproperties = get(app.csproperties, 'Data');
            numtrials    = cell2mat(csproperties(1,2:end));
            CSfreq       = cell2mat(csproperties(2,2:end));
            CSsolenoid   = [str2double(split(csproperties(3,2),'+'))',...
                            str2double(split(csproperties(3,3),'+'))',...
                            str2double(split(csproperties(3,4),'+'))'];
            assignin('base', 'csproperties', csproperties);
            CSprob       = [str2double(split(csproperties(4,2),'+'))',...
                            str2double(split(csproperties(4,3),'+'))',...
                            str2double(split(csproperties(4,4),'+'))'];
            CSopentime   = [str2double(split(csproperties(5,2),'+'))',...
                            str2double(split(csproperties(5,3),'+'))',...
                            str2double(split(csproperties(5,4),'+'))'];
            CSdur        = cell2mat(csproperties(6,2:end));
            CS_t_fxd     = [str2double(split(csproperties(7,2),'+'))',...
                            str2double(split(csproperties(7,3),'+'))',...
                            str2double(split(csproperties(7,4),'+'))'];
            CSpulse      = cell2mat(csproperties(8,2:end));
            CSspeaker    = cell2mat(csproperties(9,2:end));
            golickreq    = cell2mat(csproperties(10,2:end));
            golicktube   = cell2mat(csproperties(11,2:end));
            CSsignal = cell2mat(csproperties(12, 2:end));
            
            %ITI
            minITI = get(app.minITI,'String');
            minITI = str2double(minITI);
            maxITI = get(app.maxITI,'String');
            maxITI = str2double(maxITI);
            expitiflag = get(app.checkboxexpiti,'Value');
            maxdelaycuetovacuum = get(app.maxdelaycuetovacuum,'String');
            maxdelaycuetovacuum = str2double(maxdelaycuetovacuum);
            
            %Bgd solenoids
            
            backgroundsolenoid = get(app.backgroundsolenoid,'String');
            backgroundsolenoid = str2double(backgroundsolenoid);
            T_bgd = get(app.T_bgd,'String');
            T_bgd = str2double(T_bgd);
            r_bgd = get(app.r_bgd,'String');
            r_bgd = str2double(r_bgd);
            mindelaybgdtocue = get(app.mindelaybgdtocue,'String');
            mindelaybgdtocue = str2double(mindelaybgdtocue);
            mindelayfxdtobgd = get(app.mindelayfxdtobgd,'String');
            mindelayfxdtobgd = str2double(mindelayfxdtobgd);
            trialbytrialbgdsolenoidflag = get(app.checkboxtrialbytrial,'Value');
            totPoisssolenoid = get(app.totPoisssolenoid,'String'); % Total Poisson solenoids to deliver
            totPoisssolenoid = str2double(totPoisssolenoid);
            
            % Lick dependent rewards
            
            lickproperties  = get(app.lickproperties, 'Data');
            assignin('base', 'lickproperties', lickproperties);
            reqlicknum      = cell2mat(lickproperties(1,2:end));
            licksolenoid    = cell2mat(lickproperties(2,2:end));
            lickprob        = cell2mat(lickproperties(3,2:end));
            lickopentime    = cell2mat(lickproperties(4,2:end));
            delaytoreward   = cell2mat(lickproperties(5,2:end));
            delaytolick     = cell2mat(lickproperties(6,2:end));
            minrewards      = cell2mat(lickproperties(7,2:end));
            signaltolickreq = cell2mat(lickproperties(8,2:end));
            soundsignalpulse   = cell2mat(lickproperties(9,2:end));
            soundfreq       = cell2mat(lickproperties(10,2:end));
            sounddur        = cell2mat(lickproperties(11,2:end));
            soundspeaker    = cell2mat(lickproperties(12,2:end));
            
            %Laser
            laserlatency = get(app.laserlatency,'String');
            laserlatency = str2double(laserlatency);
            laserduration = get(app.laserduration,'String');
            laserduration = str2double(laserduration);
            randlaserflag = get(app.checkboxrandlaser,'Value');
            laserpulseperiod = get(app.laserpulseperiod,'String');
            laserpulseperiod = str2double(laserpulseperiod);
            laserpulseoffperiod = get(app.laserpulseoffperiod,'String');
            laserpulseoffperiod = str2double(laserpulseoffperiod);
            lasertrialbytrialflag = get(app.lasertrialbytrial,'Value');
            
            % Validate inputs
            inputs = [numtrials, CSfreq, CSsolenoid, CSprob, CSopentime, CSdur, CS_t_fxd,...
                      CSpulse, CSspeaker, golickreq, golicktube, CSsignal, minITI, maxITI, expitiflag,...
                      backgroundsolenoid, T_bgd, r_bgd, mindelaybgdtocue, mindelayfxdtobgd,...
                      experimentmode, trialbytrialbgdsolenoidflag, totPoisssolenoid,...
                      reqlicknum, licksolenoid, lickprob, lickopentime, delaytoreward, delaytolick,...
                      minrewards, signaltolickreq, soundsignalpulse, soundfreq, sounddur, soundspeaker,...
                      laserlatency, laserduration, randlaserflag, laserpulseperiod, laserpulseoffperiod,...
                      lasertrialbytrialflag, maxdelaycuetovacuum]; % collect all inputs into array
            
            negIn  = inputs < 0;
            intIn  = inputs - fix(inputs);
            if any([negIn intIn])
                errordlg('Invalid inputs')
                error('Invalid inputs')
            end
            
            
            % disable/enable certain options
            set(app.startButton,'Enable','on')
            set(app.sendButton,'Enable','off')
            set(app.csproperties,'Enable','off')
            set(app.lickproperties,'Enable','off')
            set(app.experimentmode,'Enable','off')
            
            set(app.minITI,'Enable','off')
            set(app.maxITI,'Enable','off')
            set(app.maxdelaycuetovacuum,'Enable','off')
            
            set(app.backgroundsolenoid,'Enable','off')
            set(app.T_bgd,'Enable','off')
            set(app.r_bgd,'Enable','off')
            set(app.mindelaybgdtocue,'Enable','off')
            set(app.mindelayfxdtobgd,'Enable','off')
            set(app.checkboxtrialbytrial,'Enable','off')
            set(app.checkboxexpiti,'Enable','off')
            set(app.totPoisssolenoid,'Enable','off')
            
            set(app.laserlatency,'Enable','off')
            set(app.laserduration,'Enable','off')
            set(app.checkboxrandlaser,'Enable','off')
            set(app.laserpulseperiod,'Enable','off')
            set(app.laserpulseoffperiod,'Enable','off')
            set(app.lasertrialbytrial,'Enable','off')
            
            set(app.testcs1,'Enable','on')
            set(app.testcs2,'Enable','on')
            set(app.testcs3,'Enable','on')
            set(app.solenoid1Button,'Enable','on')
            set(app.primesolenoid1,'Visible','on')
            set(app.solenoid2Button,'Enable','on')
            set(app.primesolenoid2,'Visible','on')
            set(app.solenoid3Button,'Enable','on')
            set(app.primesolenoid3,'Visible','on')
            set(app.solenoid4Button,'Enable','on')
            set(app.primesolenoid4,'Visible','on')
            set(app.vacuumButton,'Enable','on')
            set(app.testlaser,'Enable','on')
            
            params = sprintf('%u+', numtrials, CSfreq, CSsolenoid, CSprob, CSopentime,...
                             CSdur, CS_t_fxd, CSpulse, CSspeaker, golickreq, golicktube, CSsignal,...
                             minITI, maxITI, expitiflag, backgroundsolenoid, T_bgd, r_bgd,...
                             mindelaybgdtocue, mindelayfxdtobgd, experimentmode, trialbytrialbgdsolenoidflag,... 
                             totPoisssolenoid, reqlicknum, licksolenoid, lickprob, lickopentime, delaytoreward,...
                             delaytolick, minrewards, signaltolickreq, soundsignalpulse, soundfreq, sounddur, soundspeaker,...
                             laserlatency, laserduration, randlaserflag, laserpulseperiod,...
                             laserpulseoffperiod, lasertrialbytrialflag, maxdelaycuetovacuum);
            
            params = params(1:end-1);
            % Run arduino code
            fprintf(s,params);                                  % send info to arduino
            flushinput(s)
        end

        % Button pushed function: solenoid1Button
        function solenoid1Button_Callback(app, event)
            
            global s
            fprintf(s,'A');
        end

        % Button pushed function: solenoid2Button
        function solenoid2Button_Callback(app, event)
            % hObject    handle to solenoid2Button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global s
            fprintf(s,'D');
        end

        % Button pushed function: solenoid3Button
        function solenoid3Button_Callback(app, event)
            % hObject    handle to solenoid3Button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global s
                fprintf(s, 'G');
        end

        % Button pushed function: solenoid4Button
        function solenoid4Button_Callback(app, event)
            % hObject    handle to solenoid4Button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global s
                fprintf(s, 'J');
        end

        % Button pushed function: startButton
        function startButton_Callback(app, event)
            
            global s running actvAx saveDir
            
            % Retrieve inputs
            
            % Experiment mode
            experimentmode = get(app.experimentmode,'Value');
            
            % Cues
            csproperties = get(app.csproperties, 'Data');
            numtrials    = cell2mat(csproperties(1,2:end));
            CSfreq       = cell2mat(csproperties(2,2:end));
            CSsolenoid   = [str2double(split(csproperties(3,2),'+'))',...
                            str2double(split(csproperties(3,3),'+'))',...
                            str2double(split(csproperties(3,4),'+'))'];
            assignin('base', 'csproperties', csproperties);
            CSprob       = [str2double(split(csproperties(4,2),'+'))',...
                            str2double(split(csproperties(4,3),'+'))',...
                            str2double(split(csproperties(4,4),'+'))'];
            CSopentime   = [str2double(split(csproperties(5,2),'+'))',...
                            str2double(split(csproperties(5,3),'+'))',...
                            str2double(split(csproperties(5,4),'+'))'];
            CSdur        = cell2mat(csproperties(6,2:end));
            CS_t_fxd     = [str2double(split(csproperties(7,2),'+'))',...
                            str2double(split(csproperties(7,3),'+'))',...
                            str2double(split(csproperties(7,4),'+'))'];
            CSpulse      = cell2mat(csproperties(8,2:end));
            CSspeaker    = cell2mat(csproperties(9,2:end));
            golickreq    = cell2mat(csproperties(10,2:end));
            golicktube   = cell2mat(csproperties(11,2:end));
            CSsignal     = cell2mat(csproperties(12,2:end));
            
            %ITI
            minITI = get(app.minITI,'String');
            minITI = str2double(minITI);
            maxITI = get(app.maxITI,'String');
            maxITI = str2double(maxITI);
            expitiflag = get(app.checkboxexpiti,'Value');
            maxdelaycuetovacuum = get(app.maxdelaycuetovacuum,'String');
            maxdelaycuetovacuum = str2double(maxdelaycuetovacuum);
            
            %Bgd solenoids
            
            backgroundsolenoid = get(app.backgroundsolenoid,'String');
            backgroundsolenoid = str2double(backgroundsolenoid);
            T_bgd = get(app.T_bgd,'String');
            T_bgd = str2double(T_bgd);
            r_bgd = get(app.r_bgd,'String');
            r_bgd = str2double(r_bgd);
            mindelaybgdtocue = get(app.mindelaybgdtocue,'String');
            mindelaybgdtocue = str2double(mindelaybgdtocue);
            mindelayfxdtobgd = get(app.mindelayfxdtobgd,'String');
            mindelayfxdtobgd = str2double(mindelayfxdtobgd);
            trialbytrialbgdsolenoidflag = get(app.checkboxtrialbytrial,'Value');
            totPoisssolenoid = get(app.totPoisssolenoid,'String'); % Total Poisson solenoids to deliver
            totPoisssolenoid = str2double(totPoisssolenoid);
            
            % Lick dependent rewards
            
            lickproperties  = get(app.lickproperties, 'Data');
            assignin('base', 'lickproperties', lickproperties);
            reqlicknum      = cell2mat(lickproperties(1,2:end));
            licksolenoid    = cell2mat(lickproperties(2,2:end));
            lickprob        = cell2mat(lickproperties(3,2:end));
            lickopentime    = cell2mat(lickproperties(4,2:end));
            delaytoreward   = cell2mat(lickproperties(5,2:end));
            delaytolick     = cell2mat(lickproperties(6,2:end));
            minrewards      = cell2mat(lickproperties(7,2:end));
            signaltolickreq = cell2mat(lickproperties(8,2:end));
            soundsignalpulse   = cell2mat(lickproperties(9,2:end));
            soundfreq       = cell2mat(lickproperties(10,2:end));
            sounddur        = cell2mat(lickproperties(11,2:end));
            soundspeaker    = cell2mat(lickproperties(12,2:end));
            
            %Laser
            laserlatency = get(app.laserlatency,'String');
            laserlatency = str2double(laserlatency);
            laserduration = get(app.laserduration,'String');
            laserduration = str2double(laserduration);
            randlaserflag = get(app.checkboxrandlaser,'Value');
            laserpulseperiod = get(app.laserpulseperiod,'String');
            laserpulseperiod = str2double(laserpulseperiod);
            laserpulseoffperiod = get(app.laserpulseoffperiod,'String');
            laserpulseoffperiod = str2double(laserpulseoffperiod);
            lasertrialbytrialflag = get(app.lasertrialbytrial,'Value');
            
            % Validate inputs
            inputs = [numtrials, CSfreq, CSsolenoid, CSprob, CSopentime, CSdur, CS_t_fxd,...
                      CSpulse, CSspeaker, golickreq, golicktube, CSsignal, minITI, maxITI, expitiflag,...
                      backgroundsolenoid, T_bgd, r_bgd, mindelaybgdtocue, mindelayfxdtobgd,...
                      experimentmode, trialbytrialbgdsolenoidflag, totPoisssolenoid,...
                      reqlicknum, licksolenoid, lickprob, lickopentime, delaytoreward,...
                      delaytolick, minrewards, signaltolickreq, soundsignalpulse, soundfreq, sounddur, soundspeaker,...
                      laserlatency, laserduration, randlaserflag, laserpulseperiod, laserpulseoffperiod,...
                      lasertrialbytrialflag, maxdelaycuetovacuum]; % collect all inputs into array
            
            negIn  = inputs < 0;
            intIn  = inputs - fix(inputs);
            if any([negIn intIn])
                errordlg('Invalid inputs')
                error('Invalid inputs')
            end
            
            set(app.licks1Edit,'String','0')
            set(app.licks2Edit,'String','0')
            set(app.licks3Edit,'String','0')
            set(app.cues1Edit,'String','0')
            set(app.cues2Edit,'String','0')
            set(app.cues3Edit,'String','0')
            set(app.bgdsolenoidsEdit,'String','0')
            set(app.fxdsolenoids1Edit,'String','0')
            set(app.fxdsolenoids2Edit,'String','0')
            set(app.fxdsolenoids3Edit,'String','0')
            set(app.fxdsolenoids4Edit,'String','0')
            set(app.primesolenoid1,'Visible','off')
            set(app.primesolenoid2,'Visible','off')
            set(app.primesolenoid3,'Visible','off')
            set(app.primesolenoid4,'Visible','off')
            
            % disable/enable certain options
            set(app.startButton,'Enable','off')
            set(app.stopButton,'Enable','on')
            set(app.closeButton,'Enable','off')
            set(app.sendButton,'Enable','off')
            set(app.testcs1,'Enable','off')
            set(app.testcs2,'Enable','off')
            set(app.testcs3,'Enable','off')
            set(app.testlaser,'Enable','off')
            set(app.closeButton,'Enable','off')
            set(app.experimentmode,'Enable','off')
            set(app.uploadButton,'Enable','off')
            
            if experimentmode==2
                set(app.vacuumButton,'Enable', 'off')
            end
            
            fname = get(app.fileName,'String');
            
            params = sprintf('%u+', numtrials, CSfreq, CSsolenoid, CSprob, CSopentime,...
                             CSdur, CS_t_fxd, CSpulse, CSspeaker, golickreq, golicktube, CSsignal,...
                             minITI, maxITI, expitiflag, backgroundsolenoid, T_bgd, r_bgd, ...
                             mindelaybgdtocue, mindelayfxdtobgd, experimentmode, ...
                             trialbytrialbgdsolenoidflag, totPoisssolenoid, reqlicknum,...
                             licksolenoid, lickprob, lickopentime, delaytoreward, delaytolick,...
                             minrewards, signaltolickreq, soundsignalpulse, soundfreq, sounddur, soundspeaker,...
                             laserlatency, laserduration, randlaserflag, laserpulseperiod, laserpulseoffperiod,...
                             lasertrialbytrialflag, maxdelaycuetovacuum);
            
            params = params(1:end-1);
            
            % Run arduino code
            fprintf(s,'0');                          % Signals to Arduino to start the experiment
            conditioning_prog
            
            % Reset GUI
            set(app.startButton,'Enable','off')
            set(app.stopButton,'Enable','off')
            set(app.closeButton,'Enable','on')
            set(app.sendButton,'Enable','on')
            
            set(app.csproperties,'Enable','on')
            set(app.lickproperties,'Enable','on')
            
            set(app.minITI,'Enable','on')
            set(app.maxITI,'Enable','on')
            set(app.maxdelaycuetovacuum,'Enable','on')
            
            set(app.backgroundsolenoid,'Enable','on')
            set(app.T_bgd,'Enable','on')
            set(app.r_bgd,'Enable','on')
            set(app.mindelaybgdtocue,'Enable','on')
            set(app.mindelayfxdtobgd,'Enable','on')
            set(app.checkboxtrialbytrial,'Enable','on')
            set(app.checkboxexpiti,'Enable','on')
            set(app.totPoisssolenoid,'Enable','on')
            
            set(app.laserlatency,'Enable','on')
            set(app.laserduration,'Enable','on')
            set(app.checkboxrandlaser,'Enable','on')
            set(app.laserpulseperiod,'Enable','on')
            set(app.laserpulseoffperiod,'Enable','on')
            set(app.lasertrialbytrial,'Enable','on')
            
            set(app.testcs1,'Enable','off')
            set(app.testcs2,'Enable','off')
            set(app.testcs3,'Enable','off')
            set(app.solenoid1Button,'Enable','off')
            set(app.primesolenoid1,'Visible','off')
            set(app.solenoid2Button,'Enable','off')
            set(app.primesolenoid2,'Visible','off')
            set(app.solenoid3Button,'Enable','off')
            set(app.primesolenoid3,'Visible','off')
            set(app.solenoid4Button,'Enable','off')
            set(app.primesolenoid4,'Visible','off')
            set(app.vacuumButton,'Enable','off')
            set(app.testlaser,'Enable','off')
            
            flushinput(s);
        end

        % Button pushed function: stopButton
        function stopButton_Callback(app, event)
            
            global s running
            running = false;            % Stop running MATLAB code for monitoring arduino
            fprintf(s,'1');
        end

        % Button pushed function: testcs1
        function testcs1_Callback(app, event)
            % hObject    handle to testcs1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global s
            fprintf(s,'2');              % Send CS1 signal to arduino; 50 in the Arduino is the ASCII code for 2
            flushinput(s)
        end

        % Button pushed function: testcs2
        function testcs2_Callback(app, event)
            % hObject    handle to testcs2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global s
            fprintf(s,'3');              % Send CS2 signal to arduino; 51 in the Arduino is the ASCII code for 3
            flushinput(s)
        end

        % Button pushed function: testcs3
        function testcs3_Callback(app, event)
            % hObject    handle to testcs3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global s
            fprintf(s,'4');              % Send CS3 signal to arduino; 52 in the Arduino is the ASCII code for 4
            flushinput(s)
        end

        % Button pushed function: testlaser
        function testlaser_Callback(app, event)
            % hObject    handle to testlaser (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global s
            fprintf(s,'8');              % Send Laser signal to arduino; 56 in the Arduino is the ASCII code for 8
            flushinput(s)
        end

        % Button pushed function: uploadButton
        function uploadButton_Callback(app, event)
            % hObject    handle to uploadButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if get(app.experimentmode, 'Value') == 1
                [status,cmdout] = dos(' paste file 1 path here ');
            elseif get(app.experimentmode, 'Value') == 2
                [status,cmdout] = dos(' paste file 2 path here ');
            elseif get(app.experimentmode, 'Value') == 3
                [status,cmdout] = dos(' paste file 3 path here ');
            end
            
            
            if strcmp(str([status,cmdout]), 'avrdude done.  Thank you.')
                set(app.uploadButton, 'String', 'Successfully uploaded');
                set(app.uploadButton, 'Enable','off');
            else
                set(app.uploadButton, 'String', 'Unable to upload');
            end
        end

        % Button pushed function: vacuumButton
        function vacuumButton_Callback(app, event)
            % hObject    handle to vacuumButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global s
            fprintf(s,'V');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'headfix_GUI';
            app.HeadfixedbehaviorMenu = uimenu(app.figure1);
            app.HeadfixedbehaviorMenu.Text = 'Head-fixed behavior';
            app.prefs = uimenu(app.HeadfixedbehaviorMenu);
            app.prefs.Text = 'Preferences';
            app.CloseMenuItem = uimenu(app.HeadfixedbehaviorMenu);
            app.CloseMenuItem.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuItem_Callback, true);
            app.CloseMenuItem.Separator = 'on';
            app.CloseMenuItem.Text = 'Close';
            app.activityAxes = uiaxes(app.figure1);
            app.text24 = uilabel(app.figure1);
            app.text24.Text = '# of solenoid1';
            app.rewards = uilabel(app.figure1);
            app.rewards.Text = 'Bgd solenoids';
            app.text98 = uilabel(app.figure1);
            app.text98.Text = 'Lick3 PSTH CS3';
            app.text97 = uilabel(app.figure1);
            app.text97.Text = 'Lick3 PSTH CS2';
            app.text96 = uilabel(app.figure1);
            app.text96.Text = 'Lick3 PSTH CS1';
            app.text95 = uilabel(app.figure1);
            app.text95.Text = 'Licks3';
            app.text87 = uilabel(app.figure1);
            app.text87.Text = 'Licks2';
            app.availablePorts = uidropdown(app.figure1);
            app.availablePorts.Items = {'none found, please check connection and refresh'};
            app.availablePorts.Value = 'none found, please check connection and refresh';
            app.openButton = uibutton(app.figure1, 'push');
            app.openButton.ButtonPushedFcn = createCallbackFcn(app, @openButton_Callback, true);
            app.openButton.Text = 'Link';
            app.closeButton = uibutton(app.figure1, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Enable = 'off';
            app.closeButton.Text = 'Unlink';
            app.refreshButton = uibutton(app.figure1, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButton_Callback, true);
            app.refreshButton.Text = 'Refresh';
            app.portLabel = uilabel(app.figure1);
            app.portLabel.Text = 'Available ports:';
            app.testcs1 = uibutton(app.figure1, 'push');
            app.testcs1.ButtonPushedFcn = createCallbackFcn(app, @testcs1_Callback, true);
            app.testcs1.Enable = 'off';
            app.testcs1.Text = 'Test CS1';
            app.testcs2 = uibutton(app.figure1, 'push');
            app.testcs2.ButtonPushedFcn = createCallbackFcn(app, @testcs2_Callback, true);
            app.testcs2.Enable = 'off';
            app.testcs2.Text = 'Test CS2';
            app.fileName = uieditfield(app.figure1, 'text');
            app.fileName.Value = 'VJ_';
            app.startButton = uibutton(app.figure1, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
            app.startButton.Enable = 'off';
            app.startButton.Text = 'Start';
            app.labelPort = uilabel(app.figure1);
            app.labelPort.Text = 'Connected to: ';
            app.stopButton = uibutton(app.figure1, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButton_Callback, true);
            app.stopButton.Enable = 'off';
            app.stopButton.Text = 'Stop';
            app.port = uieditfield(app.figure1, 'text');
            app.port.Enable = 'off';
            app.port.Value = 'port not selected';
            app.labelFile = uilabel(app.figure1);
            app.labelFile.Text = 'Save as: ';
            app.licks1Edit = uieditfield(app.figure1, 'text');
            app.licks1Edit.Value = '-';
            app.cues1Edit = uieditfield(app.figure1, 'text');
            app.cues1Edit.Value = '-';
            app.text11 = uilabel(app.figure1);
            app.text11.Text = 'licks 1: ';
            app.text12 = uilabel(app.figure1);
            app.text12.Text = 'CS1 cues: ';
            app.startText = uieditfield(app.figure1, 'text');
            app.text15 = uilabel(app.figure1);
            app.text15.Text = 'Start time: ';
            app.bgdsolenoidsEdit = uieditfield(app.figure1, 'text');
            app.bgdsolenoidsEdit.Value = '-';
            app.fxdsolenoids1Edit = uieditfield(app.figure1, 'text');
            app.fxdsolenoids1Edit.Value = '-';
            app.text27 = uilabel(app.figure1);
            app.text27.Text = 'Solenoid 1';
            app.text28 = uilabel(app.figure1);
            app.text28.Text = 'CS1 cue';
            app.text29 = uilabel(app.figure1);
            app.text29.Text = 'Licks1';
            app.text30 = uilabel(app.figure1);
            app.text30.Text = 'Lick1 PSTH CS1';
            app.text31 = uilabel(app.figure1);
            app.text31.Text = 'Legend for plot';
            app.text32 = uilabel(app.figure1);
            app.text32.Text = 'CS2 cue';
            app.text33 = uilabel(app.figure1);
            app.text33.Text = 'Lick1 PSTH CS2';
            app.cues3Edit = uieditfield(app.figure1, 'text');
            app.cues3Edit.Value = '-';
            app.text34 = uilabel(app.figure1);
            app.text34.Text = 'CS3 cues: ';
            app.sendButton = uibutton(app.figure1, 'push');
            app.sendButton.ButtonPushedFcn = createCallbackFcn(app, @sendButton_Callback, true);
            app.sendButton.Enable = 'off';
            app.sendButton.Text = 'Send';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Background rewards';
            app.text2 = uilabel(app.uipanel4);
            app.text2.Text = 'Background period T_bgd';
            app.ITIlabel = uilabel(app.uipanel4);
            app.ITIlabel.Text = 'Background solenoid open time';
            app.mindelaybgdtocue = uieditfield(app.uipanel4, 'text');
            app.mindelaybgdtocue.Value = '3000';
            app.text20 = uilabel(app.uipanel4);
            app.text20.Text = {'Minimum delay between background'; ' reward and cue'};
            app.T_bgd = uieditfield(app.uipanel4, 'text');
            app.T_bgd.Value = '6000';
            app.r_bgd = uieditfield(app.uipanel4, 'text');
            app.r_bgd.Value = '0';
            app.mindelayfxdtobgd = uieditfield(app.uipanel4, 'text');
            app.mindelayfxdtobgd.Value = '3000';
            app.text25 = uilabel(app.uipanel4);
            app.text25.Text = {'Minimum delay between fixed reward '; 'and background reward'};
            app.text40 = uilabel(app.uipanel4);
            app.text40.Text = {'Run experiment with trial-by-trial '; 'background rewards?'};
            app.checkboxtrialbytrial = uicheckbox(app.uipanel4);
            app.checkboxtrialbytrial.Text = '';
            app.text58 = uilabel(app.uipanel4);
            app.text58.Text = 'Total # background rewards';
            app.totPoisssolenoid = uieditfield(app.uipanel4, 'text');
            app.totPoisssolenoid.Value = '100';
            app.text80 = uilabel(app.uipanel4);
            app.text80.Text = 'Background solenoid';
            app.backgroundsolenoid = uieditfield(app.uipanel4, 'text');
            app.backgroundsolenoid.Value = '1';
            app.testlaser = uibutton(app.figure1, 'push');
            app.testlaser.ButtonPushedFcn = createCallbackFcn(app, @testlaser_Callback, true);
            app.testlaser.Enable = 'off';
            app.testlaser.Text = 'Test LASER';
            app.testcs3 = uibutton(app.figure1, 'push');
            app.testcs3.ButtonPushedFcn = createCallbackFcn(app, @testcs3_Callback, true);
            app.testcs3.Enable = 'off';
            app.testcs3.Text = 'Test CS3';
            app.text81 = uilabel(app.figure1);
            app.text81.Text = 'CS3 cue';
            app.text82 = uilabel(app.figure1);
            app.text82.Text = 'Lick1 PSTH CS3';
            app.text83 = uilabel(app.figure1);
            app.text83.Text = 'Solenoid 2';
            app.text84 = uilabel(app.figure1);
            app.text84.Text = '# of solenoid2';
            app.fxdsolenoids2Edit = uieditfield(app.figure1, 'text');
            app.fxdsolenoids2Edit.Value = '-';
            app.cues2Edit = uieditfield(app.figure1, 'text');
            app.cues2Edit.Value = '-';
            app.text85 = uilabel(app.figure1);
            app.text85.Text = 'CS2 cues: ';
            app.text86 = uilabel(app.figure1);
            app.text86.Text = 'licks 2: ';
            app.licks2Edit = uieditfield(app.figure1, 'text');
            app.licks2Edit.Value = '-';
            app.text88 = uilabel(app.figure1);
            app.text88.Text = 'Lick2 PSTH CS1';
            app.text89 = uilabel(app.figure1);
            app.text89.Text = 'Lick2 PSTH CS2';
            app.text90 = uilabel(app.figure1);
            app.text90.Text = 'Lick2 PSTH CS3';
            app.text93 = uilabel(app.figure1);
            app.text93.Text = '* All time values are in units of ms';
            app.csproperties = uitable(app.figure1);
            app.csproperties.ColumnName = {'Variable'; 'CS1'; 'CS2'; 'CS3'};
            app.csproperties.RowName = {''};
            app.csproperties.ColumnEditable = [false true true true];
            app.text94 = uilabel(app.figure1);
            app.text94.Text = 'Solenoid 3';
            app.licks3Edit = uieditfield(app.figure1, 'text');
            app.licks3Edit.Value = '-';
            app.text99 = uilabel(app.figure1);
            app.text99.Text = 'licks 3: ';
            app.fxdsolenoids3Edit = uieditfield(app.figure1, 'text');
            app.fxdsolenoids3Edit.Value = '-';
            app.text100 = uilabel(app.figure1);
            app.text100.Text = '# of solenoid3';
            app.text101 = uilabel(app.figure1);
            app.text101.Text = '# of solenoid4';
            app.fxdsolenoids4Edit = uieditfield(app.figure1, 'text');
            app.fxdsolenoids4Edit.Value = '-';
            app.text102 = uilabel(app.figure1);
            app.text102.Text = 'Solenoid 4';
            app.lickproperties = uitable(app.figure1);
            app.lickproperties.ColumnName = {'Variable'; 'Lick tube 1'; 'Lick tube 2'};
            app.lickproperties.RowName = '';
            app.lickproperties.ColumnEditable = [false true true];
            app.uipanel16 = uipanel(app.figure1);
            app.uipanel16.Title = 'Experiment mode';
            app.experimentmode = uidropdown(app.uipanel16);
            app.experimentmode.Items = {'1: Cues with or without lick req', '2: Random rewards (lick training)', '3: Lick for rewards'};
            app.experimentmode.ValueChangedFcn = createCallbackFcn(app, @experimentmode_Callback, true);
            app.experimentmode.Value = '1: Cues with or without lick req';
            app.uploadButton = uibutton(app.uipanel16, 'push');
            app.uploadButton.ButtonPushedFcn = createCallbackFcn(app, @uploadButton_Callback, true);
            app.uploadButton.Text = 'Upload to arduino';
            app.uipanel11 = uipanel(app.figure1);
            app.uipanel11.Title = 'Solenoid 2';
            app.solenoid2Button = uibutton(app.uipanel11, 'push');
            app.solenoid2Button.ButtonPushedFcn = createCallbackFcn(app, @solenoid2Button_Callback, true);
            app.solenoid2Button.Enable = 'off';
            app.solenoid2Button.Text = 'Manual';
            app.uipanel15 = uipanel(app.figure1);
            app.uipanel15.Title = 'Solenoid4';
            app.solenoid4Button = uibutton(app.uipanel15, 'push');
            app.solenoid4Button.ButtonPushedFcn = createCallbackFcn(app, @solenoid4Button_Callback, true);
            app.solenoid4Button.Enable = 'off';
            app.solenoid4Button.Text = 'Manual';
            app.uipanel14 = uipanel(app.figure1);
            app.uipanel14.Title = 'Solenoid 3';
            app.solenoid3Button = uibutton(app.uipanel14, 'push');
            app.solenoid3Button.ButtonPushedFcn = createCallbackFcn(app, @solenoid3Button_Callback, true);
            app.solenoid3Button.Enable = 'off';
            app.solenoid3Button.Text = 'Manual';
            app.uipanel10 = uipanel(app.figure1);
            app.uipanel10.Title = 'Solenoid 1';
            app.solenoid1Button = uibutton(app.uipanel10, 'push');
            app.solenoid1Button.ButtonPushedFcn = createCallbackFcn(app, @solenoid1Button_Callback, true);
            app.solenoid1Button.Enable = 'off';
            app.solenoid1Button.Text = 'Manual';
            app.uipanel12 = uipanel(app.figure1);
            app.uipanel12.Title = 'Vacuum';
            app.vacuumButton = uibutton(app.uipanel12, 'push');
            app.vacuumButton.ButtonPushedFcn = createCallbackFcn(app, @vacuumButton_Callback, true);
            app.vacuumButton.Enable = 'off';
            app.vacuumButton.Text = 'Manual';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'ITI';
            app.text14 = uilabel(app.uipanel3);
            app.text14.Text = {'mean ITI (exponential distribution)   OR  '; 'min ITI (uniform distribution)'};
            app.maxITI = uieditfield(app.uipanel3, 'text');
            app.maxITI.Value = '90000';
            app.text17 = uilabel(app.uipanel3);
            app.text17.Text = {'max ITI (For exponential'; 'truncation of ITI = min(maxITI'; '3*meanITI))'};
            app.minITI = uieditfield(app.uipanel3, 'text');
            app.minITI.Value = '30000';
            app.checkboxexpiti = uicheckbox(app.uipanel3);
            app.checkboxexpiti.Text = '';
            app.checkboxexpiti.Value = true;
            app.text91 = uilabel(app.uipanel3);
            app.text91.Text = 'Exponential ITIs? Unticked = uniform';
            app.maxdelaycuetovacuum = uieditfield(app.uipanel3, 'text');
            app.maxdelaycuetovacuum.Value = '6000';
            app.text92 = uilabel(app.uipanel3);
            app.text92.Text = {'max delay between cue onset and '; 'vacuum. Should be longer than longest '; 'delay to reward. Sets plot range '; 'even if vacuum not connected'};
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Optogenetics';
            app.text66 = uilabel(app.uipanel1);
            app.text66.Text = 'Laser latency wrt cue';
            app.laserlatency = uieditfield(app.uipanel1, 'text');
            app.laserlatency.Value = '0';
            app.text65 = uilabel(app.uipanel1);
            app.text65.Text = 'Laser duration';
            app.text59 = uilabel(app.uipanel1);
            app.text59.Text = 'Trial-by-trial?';
            app.lasertrialbytrial = uicheckbox(app.uipanel1);
            app.lasertrialbytrial.Text = '';
            app.laserduration = uieditfield(app.uipanel1, 'text');
            app.laserduration.Value = '0';
            app.checkboxrandlaser = uicheckbox(app.uipanel1);
            app.checkboxrandlaser.Text = 'Random laser?';
            app.laserpulseoffperiod = uieditfield(app.uipanel1, 'text');
            app.laserpulseoffperiod.Value = '5000';
            app.text57 = uilabel(app.uipanel1);
            app.text57.Text = 'Laser pulse OFF period';
            app.text56 = uilabel(app.uipanel1);
            app.text56.Text = 'Laser pulse ON period';
            app.laserpulseperiod = uieditfield(app.uipanel1, 'text');
            app.laserpulseperiod.Value = '5000';
            app.light1Edit = uieditfield(app.figure1, 'text');
            app.light1Edit.Value = '-';
            app.text106 = uilabel(app.figure1);
            app.text106.Text = '# of light 1';
            app.text107 = uilabel(app.figure1);
            app.text107.Text = '# of light 2';
            app.light2Edit = uieditfield(app.figure1, 'text');
            app.light2Edit.Value = '-';
            app.text108 = uilabel(app.figure1);
            app.text108.Text = 'Light 1';
            app.text109 = uilabel(app.figure1);
            app.text109.Text = 'Light 2';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = headfix_GUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @headfix_GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end